classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        DanewejciowePanel               matlab.ui.container.Panel
        NEditField_2                    matlab.ui.control.NumericEditField
        NEditField_2Label               matlab.ui.control.Label
        mEditField                      matlab.ui.control.NumericEditField
        mEditFieldLabel                 matlab.ui.control.Label
        LiczbadodatkowychmiejscLabel    matlab.ui.control.Label
        LiczbamiejscobsugiLabel         matlab.ui.control.Label
        TEditField                      matlab.ui.control.NumericEditField
        TEditFieldLabel                 matlab.ui.control.Label
        MaksymalnyczasoczekiwaniaLabel  matlab.ui.control.Label
        FmNEditField                    matlab.ui.control.EditField
        FmNEditFieldLabel               matlab.ui.control.Label
        c_2EditField                    matlab.ui.control.NumericEditField
        c_2EditFieldLabel               matlab.ui.control.Label
        KosztamortyzacjiLabel           matlab.ui.control.Label
        c_1EditField                    matlab.ui.control.NumericEditField
        c_1EditFieldLabel               matlab.ui.control.Label
        KosztutrzymaniaLabel            matlab.ui.control.Label
        rEditField                      matlab.ui.control.NumericEditField
        rEditFieldLabel                 matlab.ui.control.Label
        rednizyskLabel                  matlab.ui.control.Label
        MEditField                      matlab.ui.control.NumericEditField
        MEditFieldLabel                 matlab.ui.control.Label
        IntensywnoobsugiLabel           matlab.ui.control.Label
        LEditField                      matlab.ui.control.NumericEditField
        LEditFieldLabel                 matlab.ui.control.Label
        IntensywnozgoszeLabel           matlab.ui.control.Label
        ChangeFuncTypeBox               matlab.ui.control.CheckBox
        FunkcjaceluDropDown             matlab.ui.control.DropDown
        FunkcjaceluDropDownLabel        matlab.ui.control.Label
        UstawieniaalgorytmuPanel        matlab.ui.container.Panel
        iterEditField                   matlab.ui.control.NumericEditField
        iterEditFieldLabel              matlab.ui.control.Label
        NEditField                      matlab.ui.control.NumericEditField
        NEditFieldLabel                 matlab.ui.control.Label
        x_maxEditField                  matlab.ui.control.NumericEditField
        x_maxLabel                      matlab.ui.control.Label
        x_minEditField                  matlab.ui.control.NumericEditField
        x_minLabel                      matlab.ui.control.Label
        dimEditField                    matlab.ui.control.NumericEditField
        dimEditFieldLabel               matlab.ui.control.Label
        IloiteracjiLabel                matlab.ui.control.Label
        IloagentwLabel                  matlab.ui.control.Label
        OgraniczeniegrneLabel           matlab.ui.control.Label
        OgraniczeniedolneLabel          matlab.ui.control.Label
        LiczbazmiennychLabel            matlab.ui.control.Label
        ButtonGroup                     matlab.ui.container.ButtonGroup
        maksymalizacjaButton            matlab.ui.control.RadioButton
        minimalizacjaButton             matlab.ui.control.RadioButton
        ObliczButton                    matlab.ui.control.Button
        Image                           matlab.ui.control.Image
        ALGORYTMSZAREGOWILKALabel       matlab.ui.control.Label
        UITable2                        matlab.ui.control.Table
        OptymalnywynikEditFieldLabel_2  matlab.ui.control.Label
        OptymalnywynikEditField         matlab.ui.control.NumericEditField
        OptymalnywynikEditFieldLabel    matlab.ui.control.Label
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    methods (Access = public)
        % function o = F7(x)
        %     F2 = 1;
        %     one = 0;
        %     two = 1;
        %     for i = 1:length(x)
        %         one = one + (x(i)^2/4000);
        %         two = two * (cos(x(i)/sqrt(i)));
        %     end
        %     o = F2 + one + two;
        % end

        function p_utr= p_utr(~, m)
            
            delta = 1 / T;
            beta = delta / M;
            ro = L / M;
            
            sum_nomi = 0;
            
            for r=1:m+N
                il = 1;
                for n=1:r
                    il = il * (m + n*beta);
                end
                sum_nomi = sum_nomi + (r * ro^r) / il;
            end

            nomi = (beta / ro) * (ro^m / factorial(m)) * sum_nomi;
            
            sum_denomi1 = 0;
            for k = 0:m
                sum_denomi1 = sum_denomi1 + (ro^k)/factorial(k);
            end

            sum_denomi2 = 0;
            for r=1:m+N
                il = 1;
                for n=1:r
                    il = il * (m + n*beta);
                end
                sum_denomi2 = sum_denomi2 + (ro^r)/il;
            end

            denomi = sum_denomi1 + sum_denomi2 * (ro^m)/(factorial(m));

            p_w = nomi / denomi;

            p0 = denomi^(-1);
            pm = ((ro^m)/(factorial(m))) * p0;

            p_utr = p_w + pm;
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ObliczButton
        function ObliczButtonPushed(app, event)
            
            agents_num = app.NEditField.Value;
            iterations = app.iterEditField.Value;
            lb=app.x_minEditField.Value; 
            ub=app.x_maxEditField.Value;
            dim=app.dimEditField.Value;
            if app.minimalizacjaButton.Value
                optType = 'MIN';
            else
                optType = 'MAX';
            end
            fun = 0;


            funBoxValue = app.ChangeFuncTypeBox.Value;
            if funBoxValue
                % lambda
                L = app.LEditField.Value;
                % mi
                M = app.MEditField.Value;
                % czas oczekiwania
                T = app.TEditField.Value;
                % ograniczenie 1
                c_1 = app.c_1EditField.Value;
                %ograniczenie 2
                c_2 = app.c_2EditField.Value;
                % zysk
                r = app.c_2EditField.Value;
                % obsługa
                m = app.mEditField.Value;
                % miejsca
                N = app.NEditField_2.Value;

                funEditTextVal = app.FmNEditField.Value;
                try
                    f = eval(strcat("@(m) ", funEditTextVal));
                    fun_map = @(arr) sum(arrayfun(f, arr));
                    fun = fun_map;
                catch
                    app.FmNEditField.Value = "#ERR";
                    return;
                end
            else
                funcValue = app.FunkcjaceluDropDown.Value;

                switch funcValue
                    case "hyper_ellipse"
                        fun = @hyper_ellipse;
                    case "griewangk"
                        fun = @griewangk;
                    case "rosenbrock"
                        fun = @rosenbrock;
                    case "schwefel"
                        fun = @schwefel;
                end
            end
            [best_score, best_pos, convergence_plot] = gray_wolf_opt(fun, dim, lb, ub, agents_num, iterations, optType);

            %dziala, nareszczie
            %
            % w nienawisci do Matlaba zostalem wychowany
            %w mym domu Matlab wiec bedzie jebany
            %bo ten jezyk jest kurwa niedojebany
            
            x = -10:0.1:10;
            y = x;
            l = length(x);
            z = zeros(length(x), length(y));
            for i = 1:l
                for j=1:l
                    z(i,j) = fun([x(i), y(j)]);
                end
            end


            %cyferki do testow
            % agents_num = 30;
            % iterations = 1000;
            % lb=-600;
            % ub=600;
            % dim=100;

            % [best_score, best_pos, convergence_plot] = gray_wolf_opt(fun, dim, lb, ub, agents_num, iterations, optType);
            % 
            % %dziala, nareszczie
            % %
            % % w nienawisci do Matlaba zostalem wychowany
            % %w mym domu Matlab wiec bedzie jebany
            % %bo ten jezyk jest kurwa niedojebany
            % 
            % x = -10:0.1:10;
            % y = x;
            % l = length(x);
            % z = zeros(length(x), length(y));
            % for i = 1:l
            %     for j=1:l
            %         z(i,j) = fun([x(i), y(j)]);
            %     end
            % end
            plot(app.UIAxes,1,2)
            surfc(app.UIAxes,x,y,z, 'LineStyle', 'none');
            %sukces znowu to kurestwo zadzialalo xD
  
            plot(app.UIAxes2,1,2);
            semilogy(app.UIAxes2,convergence_plot,'Color','r')
            
            %to co zakomentowane to mozna w razie potrzeby probowac recznie ustawic
            %chyba ustawilem dobrze ale zostawiam to czego pewny nie jestem
            %zakomentowane wrazie w jakby trzeba bylo poprawic zeby bylo
            %wiadomo co
            axis(app.UIAxes2,"tight")
            %box(on)
       
            legend(app.UIAxes2,'GWO')

            app.OptymalnywynikEditField.Value = best_score;
            app.UITable2.Data = best_pos;


        end

        % Value changed function: ChangeFuncTypeBox
        function ChangeFuncTypeBoxValueChanged(app, event)
            value = app.ChangeFuncTypeBox.Value;
            if value
                app.FunkcjaceluDropDown.Enable = false;
                app.LEditField.Enable = true;
                app.MEditField.Enable = true;
                app.c_1EditField.Enable = true;
                app.c_2EditField.Enable = true;
                app.rEditField.Enable = true;
                app.TEditField.Enable = true;
                app.FmNEditField.Enable = true;
                app.mEditField.Enable = true;
                app.NEditField_2.Enable = true;
            else
                app.FunkcjaceluDropDown.Enable = true;
                app.LEditField.Enable = false;
                app.MEditField.Enable = false;
                app.c_1EditField.Enable = false;
                app.c_2EditField.Enable = false;
                app.rEditField.Enable = false;
                app.TEditField.Enable = false;
                app.FmNEditField.Enable = false;
                app.mEditField.Enable = false;
                app.NEditField_2.Enable = false;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Przestrzeń parametrów')
            xlabel(app.UIAxes, 'x_1')
            ylabel(app.UIAxes, 'x_2''')
            zlabel(app.UIAxes, 'F( x_1 , x_2 )')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Przestrzeń objektowa')
            xlabel(app.UIAxes2, 'Iteracja')
            ylabel(app.UIAxes2, 'Uzyskane wyniki')
            zlabel(app.UIAxes2, 'Z')
            app.OptymalnywynikEditFieldLabel = uilabel(app.UIFigure);
            app.OptymalnywynikEditFieldLabel.Text = 'Optymalny wynik';
            app.OptymalnywynikEditField = uieditfield(app.UIFigure, 'numeric');
            app.OptymalnywynikEditFieldLabel_2 = uilabel(app.UIFigure);
            app.OptymalnywynikEditFieldLabel_2.Text = 'Optymalne pozycje';
            app.UITable2 = uitable(app.UIFigure);
            app.UITable2.ColumnName = {'Pozycja'};
            app.UITable2.RowName = {};
            app.ALGORYTMSZAREGOWILKALabel = uilabel(app.UIFigure);
            app.ALGORYTMSZAREGOWILKALabel.Text = 'ALGORYTM SZAREGO WILKA';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'assets', 'profesor-rafal-wilczur.jpg');
            app.UstawieniaalgorytmuPanel = uipanel(app.UIFigure);
            app.UstawieniaalgorytmuPanel.Title = 'Ustawienia algorytmu';
            app.ObliczButton = uibutton(app.UstawieniaalgorytmuPanel, 'push');
            app.ObliczButton.ButtonPushedFcn = createCallbackFcn(app, @ObliczButtonPushed, true);
            app.ObliczButton.Text = 'Oblicz';
            app.ButtonGroup = uibuttongroup(app.UstawieniaalgorytmuPanel);
            app.minimalizacjaButton = uiradiobutton(app.ButtonGroup);
            app.minimalizacjaButton.Text = 'minimalizacja';
            app.minimalizacjaButton.Value = true;
            app.maksymalizacjaButton = uiradiobutton(app.ButtonGroup);
            app.maksymalizacjaButton.Text = 'maksymalizacja';
            app.LiczbazmiennychLabel = uilabel(app.UstawieniaalgorytmuPanel);
            app.LiczbazmiennychLabel.Text = 'Liczba zmiennych';
            app.OgraniczeniedolneLabel = uilabel(app.UstawieniaalgorytmuPanel);
            app.OgraniczeniedolneLabel.Text = 'Ograniczenie dolne';
            app.OgraniczeniegrneLabel = uilabel(app.UstawieniaalgorytmuPanel);
            app.OgraniczeniegrneLabel.Text = 'Ograniczenie górne';
            app.IloagentwLabel = uilabel(app.UstawieniaalgorytmuPanel);
            app.IloagentwLabel.Text = 'Ilość agentów';
            app.IloiteracjiLabel = uilabel(app.UstawieniaalgorytmuPanel);
            app.IloiteracjiLabel.Text = 'Ilość iteracji';
            app.dimEditFieldLabel = uilabel(app.UstawieniaalgorytmuPanel);
            app.dimEditFieldLabel.Text = '(dim)';
            app.dimEditField = uieditfield(app.UstawieniaalgorytmuPanel, 'numeric');
            app.x_minLabel = uilabel(app.UstawieniaalgorytmuPanel);
            app.x_minLabel.Text = '(x_min)';
            app.x_minEditField = uieditfield(app.UstawieniaalgorytmuPanel, 'numeric');
            app.x_maxLabel = uilabel(app.UstawieniaalgorytmuPanel);
            app.x_maxLabel.Text = '(x_max)';
            app.x_maxEditField = uieditfield(app.UstawieniaalgorytmuPanel, 'numeric');
            app.NEditFieldLabel = uilabel(app.UstawieniaalgorytmuPanel);
            app.NEditFieldLabel.Text = '(N)';
            app.NEditField = uieditfield(app.UstawieniaalgorytmuPanel, 'numeric');
            app.iterEditFieldLabel = uilabel(app.UstawieniaalgorytmuPanel);
            app.iterEditFieldLabel.Text = '(iter)';
            app.iterEditField = uieditfield(app.UstawieniaalgorytmuPanel, 'numeric');
            app.DanewejciowePanel = uipanel(app.UIFigure);
            app.DanewejciowePanel.Title = 'Dane wejściowe';
            app.FunkcjaceluDropDownLabel = uilabel(app.DanewejciowePanel);
            app.FunkcjaceluDropDownLabel.Text = 'Funkcja celu';
            app.FunkcjaceluDropDown = uidropdown(app.DanewejciowePanel);
            app.FunkcjaceluDropDown.Items = {'hyper_ellipse', 'griewangk', 'rosenbrock', 'schwefel'};
            app.FunkcjaceluDropDown.ItemsData = {'hyper_ellipse', 'griewangk', 'rosenbrock', 'schwefel'};
            app.FunkcjaceluDropDown.Value = 'hyper_ellipse';
            app.ChangeFuncTypeBox = uicheckbox(app.DanewejciowePanel);
            app.ChangeFuncTypeBox.ValueChangedFcn = createCallbackFcn(app, @ChangeFuncTypeBoxValueChanged, true);
            app.ChangeFuncTypeBox.Text = 'Kreator funkcji celu systemu kolejkowego ze stratami';
            app.IntensywnozgoszeLabel = uilabel(app.DanewejciowePanel);
            app.IntensywnozgoszeLabel.Text = 'Intensywność zgłoszeń';
            app.LEditFieldLabel = uilabel(app.DanewejciowePanel);
            app.LEditFieldLabel.Text = '( L )';
            app.LEditField = uieditfield(app.DanewejciowePanel, 'numeric');
            app.LEditField.Enable = 'off';
            app.IntensywnoobsugiLabel = uilabel(app.DanewejciowePanel);
            app.IntensywnoobsugiLabel.Text = 'Intensywność obsługi';
            app.MEditFieldLabel = uilabel(app.DanewejciowePanel);
            app.MEditFieldLabel.Text = '( M )';
            app.MEditField = uieditfield(app.DanewejciowePanel, 'numeric');
            app.MEditField.Enable = 'off';
            app.rednizyskLabel = uilabel(app.DanewejciowePanel);
            app.rednizyskLabel.Text = 'Średni zysk';
            app.rEditFieldLabel = uilabel(app.DanewejciowePanel);
            app.rEditFieldLabel.Text = '( r )';
            app.rEditField = uieditfield(app.DanewejciowePanel, 'numeric');
            app.rEditField.Enable = 'off';
            app.KosztutrzymaniaLabel = uilabel(app.DanewejciowePanel);
            app.KosztutrzymaniaLabel.Text = 'Koszt utrzymania';
            app.c_1EditFieldLabel = uilabel(app.DanewejciowePanel);
            app.c_1EditFieldLabel.Text = '( c_1 )';
            app.c_1EditField = uieditfield(app.DanewejciowePanel, 'numeric');
            app.c_1EditField.Enable = 'off';
            app.KosztamortyzacjiLabel = uilabel(app.DanewejciowePanel);
            app.KosztamortyzacjiLabel.Text = 'Koszt amortyzacji';
            app.c_2EditFieldLabel = uilabel(app.DanewejciowePanel);
            app.c_2EditFieldLabel.Text = '( c_2 )';
            app.c_2EditField = uieditfield(app.DanewejciowePanel, 'numeric');
            app.c_2EditField.Enable = 'off';
            app.FmNEditFieldLabel = uilabel(app.DanewejciowePanel);
            app.FmNEditFieldLabel.Text = 'F(m,N) =';
            app.FmNEditField = uieditfield(app.DanewejciowePanel, 'text');
            app.FmNEditField.Enable = 'off';
            app.FmNEditField.Placeholder = 'np. L*(1-p_u)*r - c_1*(m+N) - c_2*m';
            app.MaksymalnyczasoczekiwaniaLabel = uilabel(app.DanewejciowePanel);
            app.MaksymalnyczasoczekiwaniaLabel.Text = 'Maksymalny czas oczekiwania';
            app.TEditFieldLabel = uilabel(app.DanewejciowePanel);
            app.TEditFieldLabel.Text = '( T )';
            app.TEditField = uieditfield(app.DanewejciowePanel, 'numeric');
            app.TEditField.Enable = 'off';
            app.LiczbamiejscobsugiLabel = uilabel(app.DanewejciowePanel);
            app.LiczbamiejscobsugiLabel.Text = 'Liczba miejsc obsługi';
            app.LiczbadodatkowychmiejscLabel = uilabel(app.DanewejciowePanel);
            app.LiczbadodatkowychmiejscLabel.Text = 'Liczba dodatkowych miejsc';
            app.mEditFieldLabel = uilabel(app.DanewejciowePanel);
            app.mEditFieldLabel.Text = '( m )';
            app.mEditField = uieditfield(app.DanewejciowePanel, 'numeric');
            app.mEditField.Enable = 'off';
            app.NEditField_2Label = uilabel(app.DanewejciowePanel);
            app.NEditField_2Label.Text = '( N )';
            app.NEditField_2 = uieditfield(app.DanewejciowePanel, 'numeric');
            app.NEditField_2.Enable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end